classdef parameter_tuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        tau2EditField         matlab.ui.control.NumericEditField
        tau2EditFieldLabel    matlab.ui.control.Label
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        NextButton            matlab.ui.control.Button
        PatientDropDown       matlab.ui.control.DropDown
        PatientDropDownLabel  matlab.ui.control.Label
        TimeSlider            matlab.ui.control.Slider
        TimeSliderLabel       matlab.ui.control.Label
        lambdaEditField       matlab.ui.control.NumericEditField
        lambdaLabel           matlab.ui.control.Label
        A1EditField           matlab.ui.control.NumericEditField
        A1EditFieldLabel      matlab.ui.control.Label
        tau1EditField         matlab.ui.control.NumericEditField
        tau1EditFieldLabel    matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        FitButton             matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pars
        rescaled
        it
        pt
        plt1
        plt2% Description
        synFun % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dataFolder = 'E:\Research_Projects\004_Propofol\manuscript\Version3\Data';
            app.rescaled = load(fullfile(dataFolder,'data_rescaled_detrended.mat'));
            %data = load('E:\Research_Projects\004_Propofol\data\experiments\scalp_EEG\analyzed\electrodes\electrode1');
            dataFolder = 'E:\Research_Projects\004_Propofol\manuscript\Version3\Data';
            load(fullfile(dataFolder,'data_time_information.mat'));
            t0 = timeInfo.infusion_onset-timeInfo.object_drop;
            %for i = 1:14
            %    app.rescaled.psd(:,:,i) = log10(interp1(-data.time/t0(i),data.psd(:,:,i)',app.rescaled.time)');
            %end

            load('E:\Research_Projects\004_Propofol\data\experiments\scalp_EEG\model_fits\20230701\param_save.mat','pars')
            %app.pars = permute(app.rescaled.synPars,[2,1,3]);
            %pars(:,2,:) = 4e-3;
            app.pars = pars;
            app.it = app.TimeSlider.Value;
            app.pt = str2num(app.PatientDropDown.Value);
            [~,app.synFun] = fittingmodel('exp2');


            %axes(app.UIAxes)
            app.plt1 = plot(app.rescaled.freq,10.^app.rescaled.psd(:,app.it,app.pt),'k','LineWidth',1,'parent',app.UIAxes); 
            hold(app.UIAxes,'on');
            app.plt2 = plot(app.rescaled.freq,10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt)),'LineWidth',1,'color','b','parent',app.UIAxes); 
            set(app.UIAxes,'xscale','log');
            set(app.UIAxes,'yscale','log');
            xlim(app.UIAxes,[0.5,100]);
            xticks(app.UIAxes,[0.5,5,50]);
            xticklabels(app.UIAxes,[0.5,5,50]);
            set(get(app.UIAxes,'xaxis'),'MinorTick','off');
            set(get(app.UIAxes,'yaxis'),'MinorTick','off');
            xlabel(app.UIAxes,'Frequency (Hz)');
            ylim(app.UIAxes,[1e-4,1e4]);
            yticks(app.UIAxes,10.^[-2:2:4]);
            ylabel(app.UIAxes,['PSD (' char(956) 'V^2/Hz)']);
            drawnow;

            app.tau1EditField.Value = round(app.pars(app.it,1,app.pt)*1e3,2);
            app.tau2EditField.Value = round(app.pars(app.it,2,app.pt)*1e3,2);
            app.A1EditField.Value = round(app.pars(app.it,3,app.pt),2);
            app.lambdaEditField.Value = round(app.pars(app.it,4,app.pt),2);
        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
            app.pt = str2num(app.PatientDropDown.Value);
            app.plt1.YData = 10.^app.rescaled.psd(:,app.it,app.pt);
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.it = ceil(app.TimeSlider.Value);
            app.TimeSlider.Value = app.it;
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
            app.tau2EditField.Value = round(app.pars(app.it,2,app.pt)*1e3,2);
            app.plt1.YData = 10.^app.rescaled.psd(:,app.it,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TimeSlider.Value = min(200,app.it+1);
            app.it = app.TimeSlider.Value;
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
            app.plt1.YData = 10.^app.rescaled.psd(:,app.it,app.pt);
            app.tau2EditField.Value = round(app.pars(app.it,2,app.pt)*1e3,2);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TimeSlider.Value = max(1,app.it-1);
            app.it = app.TimeSlider.Value;
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.tau2EditField.Value = round(app.pars(app.it,2,app.pt)*1e3,2);
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
            app.plt1.YData = 10.^app.rescaled.psd(:,app.it,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));             
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.pars(app.it,4,app.pt) = app.lambdaEditField.Value;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));  
        end

        % Value changed function: A1EditField
        function A1EditFieldValueChanged(app, event)
            app.pars(app.it,3,app.pt) = app.A1EditField.Value;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));  
        end

        % Value changed function: tau1EditField
        function tau1EditFieldValueChanged(app, event)
            app.pars(app.it,1,app.pt) = app.tau1EditField.Value*1e-3;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt)); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            pars = app.pars;
            save('E:\Research_Projects\004_Propofol\data\experiments\scalp_EEG\model_fits\20230701\param_save','pars')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.pars(app.it,1,app.pt) = app.pars(app.it,1,app.pt)+0.5e-3;
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt)); 
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.pars(app.it,1,app.pt) = app.pars(app.it,1,app.pt)-0.5e-3;
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));             
        end

        % Button pushed function: Button_4
        function Button4(app, event)
            app.pars(app.it,4,app.pt) = app.pars(app.it,4,app.pt)+0.1;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));   
                
        end

        % Button pushed function: Button_3
        function Button3(app, event)
            app.pars(app.it,3,app.pt) = app.pars(app.it,3,app.pt)+0.1;
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));          
        end

        % Button pushed function: Button_5
        function Button5(app, event)

             app.pars(app.it,3,app.pt) = app.pars(app.it,3,app.pt)-0.1;
             app.A1EditField.Value = app.pars(app.it,3,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));                  
        end

        % Button pushed function: Button_6
        function Button6(app, event)
            app.pars(app.it,4,app.pt) = app.pars(app.it,4,app.pt)-0.1;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt));                  
        end

        % Value changed function: tau2EditField
        function tau2EditFieldValueChanged(app, event)
            app.pars(app.it,2,app.pt) = app.tau2EditField.Value*1e-3;
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt)); 
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            x = app.rescaled.freq;
            y = 10.^app.rescaled.psd(:,app.it,app.pt);
            p0 = synDetrend(x(x<100),y(x<100),2,'exp2');
            app.pars(app.it,1:4,app.pt) = p0(1:4);
            app.plt2.YData = 10.^app.synFun(app.rescaled.freq,app.pars(app.it,:,app.pt)); 
           
            app.tau1EditField.Value = app.pars(app.it,1,app.pt)*1e3;
            app.lambdaEditField.Value = app.pars(app.it,4,app.pt);
            app.tau2EditField.Value = round(app.pars(app.it,2,app.pt)*1e3,2);
            app.A1EditField.Value = app.pars(app.it,3,app.pt);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {378, 378};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tau1EditFieldLabel = uilabel(app.LeftPanel);
            app.tau1EditFieldLabel.Text = 'tau1';
            app.tau1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.tau1EditField.ValueChangedFcn = createCallbackFcn(app, @tau1EditFieldValueChanged, true);
            app.A1EditFieldLabel = uilabel(app.LeftPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.A1EditField.ValueChangedFcn = createCallbackFcn(app, @A1EditFieldValueChanged, true);
            app.lambdaLabel = uilabel(app.LeftPanel);
            app.lambdaLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.TimeSliderLabel = uilabel(app.LeftPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.LeftPanel);
            app.TimeSlider.Limits = [1 200];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 1;
            app.PatientDropDownLabel = uilabel(app.LeftPanel);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.LeftPanel);
            app.PatientDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = '1';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.LeftPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '^';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '\/';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button3, true);
            app.Button_3.Text = '^';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button4, true);
            app.Button_4.Text = '^';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button5, true);
            app.Button_5.Text = '\/';
            app.Button_6 = uibutton(app.LeftPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button6, true);
            app.Button_6.Text = '\/';
            app.tau2EditFieldLabel = uilabel(app.LeftPanel);
            app.tau2EditFieldLabel.Text = 'tau2';
            app.tau2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.tau2EditField.ValueChangedFcn = createCallbackFcn(app, @tau2EditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FitButton = uibutton(app.RightPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameter_tuner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end